classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        step_sizeSpinner         matlab.ui.control.Spinner
        step_sizeSpinnerLabel    matlab.ui.control.Label
        fromsizeSpinner_2        matlab.ui.control.Spinner
        fromsizeSpinner_2Label   matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        stepEditField            matlab.ui.control.EditField
        stepEditFieldLabel       matlab.ui.control.Label
        Tol_tEditField           matlab.ui.control.NumericEditField
        Tol_tEditFieldLabel      matlab.ui.control.Label
        Tol_NEditField           matlab.ui.control.NumericEditField
        Tol_NEditFieldLabel      matlab.ui.control.Label
        d0EditField              matlab.ui.control.EditField
        d0EditFieldLabel         matlab.ui.control.Label
        DisplayButtonGroup       matlab.ui.container.ButtonGroup
        CurveTracingButton_2     matlab.ui.control.RadioButton
        GridButton               matlab.ui.control.RadioButton
        BothButton               matlab.ui.control.RadioButton
        tosizeSpinner            matlab.ui.control.Spinner
        tosizeSpinnerLabel       matlab.ui.control.Label
        step_threadSpinner       matlab.ui.control.Spinner
        step_threadSpinnerLabel  matlab.ui.control.Label
        tothreadSpinner          matlab.ui.control.Spinner
        tothreadSpinnerLabel     matlab.ui.control.Label
        fromthreadSpinner        matlab.ui.control.Spinner
        fromthreadSpinnerLabel   matlab.ui.control.Label
        StartmeasurementsButton  matlab.ui.control.StateButton
        MEditField               matlab.ui.control.NumericEditField
        MEditFieldLabel          matlab.ui.control.Label
        RecalculateButton        matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        EpsilonEditField         matlab.ui.control.EditField
        EpsilonEditFieldLabel    matlab.ui.control.Label
        ymaxEditField            matlab.ui.control.NumericEditField
        ymaxEditFieldLabel       matlab.ui.control.Label
        yminEditField            matlab.ui.control.NumericEditField
        yminEditFieldLabel       matlab.ui.control.Label
        xmaxEditField            matlab.ui.control.NumericEditField
        xmaxEditFieldLabel       matlab.ui.control.Label
        xminEditField            matlab.ui.control.NumericEditField
        xminEditFieldLabel       matlab.ui.control.Label
        selectareaButton         matlab.ui.control.Button
        performancetestButton    matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        NEditField               matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        matriceListBox           matlab.ui.control.ListBox
        matriceListBoxLabel      matlab.ui.control.Label
        StartButton              matlab.ui.control.StateButton
        AlgorithmButtonGroup     matlab.ui.container.ButtonGroup
        CompareButton            matlab.ui.control.RadioButton
        CurveTracingButton       matlab.ui.control.RadioButton
        GridButton_2             matlab.ui.control.RadioButton
        threadsSpinner           matlab.ui.control.Spinner
        threadsSpinnerLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        epsilon = 0.1; % Description
        generate_matrix = @(n) toeplitz_matrix(n); %fonction pour générer la matrice 
        %taille de la trice (initiliasé par déaut à 20). 
        N = 50;
        %matrice A
        A;
        %rectangle dessiné.
        roi; % The app display
        thread = 1;
        max_tread = feature('numcores');
        stress_test = false;
        from_thread = 1;
        to_thread = 2;
        from_size = 10;
        to_size = 20;
        step = 10; 
        d0 = 1+1i;
        tol_Newton = 0.01;
        steps = 0.1;
        tol_turn = 0.01;
        step_thread = 1;
        switch_S_E = "Efficiency";
        h; % The contourf display
        sct; %The scatter display
        grid = @(A,epsilon,thread,m) gridPseudospectrum_par(A,epsilon,thread,m); %Grid algorithme
        both = @(A,epsilon,m,d0,tol_Newton,tol_turn,thread,step,gui) display_grid_curve(A,epsilon,m,d0,tol_Newton,tol_turn,thread,step,gui);%Display both algorithm
        curve_tracing = @(A,epsilon,d0,tol_Newton,tol_turn,thread,steps) curve_tracing_par(A,epsilon,d0,tol_Newton, tol_turn,thread,steps);
        id = 1;
        algo; %Chosen algorithm 
        m=50;
        perf_computed = false;
        time_mesurment_curve;
        size_matrix;
        threads;
    end
    
    methods (Access = private)
        %modifie les valeurs de xmin, xmax, ymin, ymax en temps réelle. 
        function allevents(app, ~, evt)
            position = evt.CurrentPosition;
            app.xminEditField.Value = position(1);
            app.yminEditField.Value = position(2);
            app.xmaxEditField.Value = position(1) + position(3);
            app.ymaxEditField.Value = position(2) + position(4);
        end
        

        function deleteRoi(app,~,~)
            app.quitting_for_select_area();
        end

        function configuration_for_select_area(app)
            app.performancetestButton.Visible = 'off';
            app.LoadButton.Visible = 'off';
            app.NEditField.Visible = 'off';
            app.NEditFieldLabel.Visible = 'off';
            app.EpsilonEditField.Visible = 'off';
            app.EpsilonEditFieldLabel.Visible = 'off';
            app.matriceListBox.Visible ='off';
            app.matriceListBoxLabel.Visible = 'off';
            app.AlgorithmButtonGroup.Visible = 'off';
            app.CurveTracingButton.Visible = 'off';
            app.GridButton_2.Visible = 'off';
            app.threadsSpinner.Visible = 'off';
            app.threadsSpinnerLabel.Visible = 'off';
            app.selectareaButton.Enable = 'off';
            app.xmaxEditField.Visible = 'on';
            app.xminEditField.Visible = 'on';
            app.xmaxEditFieldLabel.Visible = 'on';
            app.xminEditFieldLabel.Visible = 'on';
            app.ymaxEditField.Visible = 'on';
            app.yminEditField.Visible = 'on';
            app.ymaxEditFieldLabel.Visible = 'on';
            app.yminEditFieldLabel.Visible = 'on';
            app.xminEditFieldLabel.Enable = 'on';
            app.xmaxEditFieldLabel.Enable = 'on';
            app.yminEditFieldLabel.Enable = 'on';
            app.ymaxEditFieldLabel.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.RecalculateButton.Visible = 'on';
            app.RecalculateButton.Enable = 'on';
            app.CancelButton.Enable = 'on';
            app.CancelButton.Visible = 'on';
        end

        function quitting_for_select_area(app)
            app.performancetestButton.Visible = 'on';
            app.LoadButton.Visible = 'off';
            app.NEditField.Visible = 'on';
            app.NEditFieldLabel.Visible = 'on';
            app.EpsilonEditField.Visible = 'on';
            app.EpsilonEditFieldLabel.Visible = 'on';
            app.matriceListBox.Visible ='on';
            app.matriceListBoxLabel.Visible = 'on';
            app.AlgorithmButtonGroup.Visible = 'on';
            app.CurveTracingButton.Visible = 'on';
            app.GridButton_2.Visible = 'on';
            app.threadsSpinner.Visible = 'on';
            app.threadsSpinnerLabel.Visible = 'on';
            app.selectareaButton.Enable = 'on';
            app.xmaxEditField.Visible = 'off';
            app.xminEditField.Visible = 'off';
            app.xmaxEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Visible = 'off';
            app.ymaxEditField.Visible = 'off';
            app.yminEditField.Visible = 'off';
            app.ymaxEditFieldLabel.Visible = 'off';
            app.yminEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Enable = 'off';
            app.xmaxEditFieldLabel.Enable = 'off';
            app.yminEditFieldLabel.Enable = 'off';
            app.ymaxEditFieldLabel.Enable = 'off';
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            app.RecalculateButton.Visible = 'off';
            app.RecalculateButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.CancelButton.Visible = 'off';
            app.StartmeasurementsButton.Visible = 'off';
            app.StartmeasurementsButton.Enable = 'off';
            app.fromthreadSpinner.Visible = 'off';
            app.fromthreadSpinnerLabel.Visible = 'off';
            app.fromthreadSpinnerLabel.Enable = 'off';
            app.fromthreadSpinner.Enable = 'off';
            app.tothreadSpinner.Visible = 'off';
            app.tothreadSpinnerLabel.Visible = 'off';
            app.tothreadSpinnerLabel.Enable = 'off';
            app.tothreadSpinner.Enable = 'off';

            app.tosizeSpinner.Visible = 'off';
            app.tosizeSpinnerLabel.Visible = 'off';
            app.tosizeSpinnerLabel.Enable = 'off';
            app.tosizeSpinner.Enable = 'off';

            app.step_threadSpinner.Visible = 'off';
            app.step_threadSpinnerLabel.Visible = 'off';
            app.step_threadSpinnerLabel.Enable = 'off';
            app.step_threadSpinner.Enable = 'off';
            app.Switch.Visible = 'off';
            app.CompareButton.Visible = 'on';
            app.step_sizeSpinner.Visible = "off";
            app.step_sizeSpinnerLabel.Visible = "off";
            app.fromsizeSpinner_2.Visible = "off";
            app.fromsizeSpinner_2Label.Visible = "off";

        end
        
        function configuration_for_perf_test(app)
            app.performancetestButton.Visible = 'off';
            app.LoadButton.Visible = 'off';
            app.CurveTracingButton.Visible = 'on';
            app.selectareaButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.CancelButton.Enable = 'on';
            app.CancelButton.Visible = 'on';
            app.StartmeasurementsButton.Visible = 'on';
            app.StartmeasurementsButton.Enable = 'on';
            app.NEditField.Visible = 'off';
            app.NEditFieldLabel.Visible = 'off';
            app.threadsSpinner.Visible = 'off';
            app.threadsSpinnerLabel.Visible = 'off';
            app.fromthreadSpinner.Visible = 'on';
            app.fromthreadSpinnerLabel.Visible = 'on';
            app.fromthreadSpinnerLabel.Enable = 'on';
            app.fromthreadSpinner.Enable = 'on';
            app.tothreadSpinner.Visible = 'on';
            app.tothreadSpinnerLabel.Visible = 'on';
            app.tothreadSpinnerLabel.Enable = 'on';
            app.tothreadSpinner.Enable = 'on';
            
            app.tosizeSpinner.Visible = 'on';
            app.tosizeSpinnerLabel.Visible = 'on';
            app.tosizeSpinnerLabel.Enable = 'on';
            app.tosizeSpinner.Enable = 'on';

            app.step_threadSpinner.Visible = 'on';
            app.step_threadSpinnerLabel.Visible = 'on';
            app.step_threadSpinnerLabel.Enable = 'on';
            app.step_threadSpinner.Enable = 'on';
            app.Switch.Visible = 'on';
            app.CompareButton.Visible = 'off';
            app.step_sizeSpinner.Visible = "on";
            app.step_sizeSpinnerLabel.Visible = "on";
            app.fromsizeSpinner_2.Visible = "on";
            app.fromsizeSpinner_2Label.Visible = "on";

            
        end
        
        function configuration_for_both_algo(app)
            app.Tol_NEditField.Visible = 'on';
            app.Tol_NEditFieldLabel.Visible = 'on';
            app.Tol_tEditField.Visible = 'on';
            app.Tol_tEditFieldLabel.Visible = 'on';
            app.d0EditField.Visible = 'on';
            app.d0EditFieldLabel.Visible = 'on';
            app.MEditField.Visible = "on";
            app.MEditFieldLabel.Visible = "on";
            app.stepEditField.Visible = "on";
            app.stepEditFieldLabel.Visible = "on";
        end

        function configuration_for_grid(app)
            app.Tol_NEditField.Visible = 'off';
            app.Tol_NEditFieldLabel.Visible = 'off';
            app.Tol_tEditField.Visible = 'off';
            app.Tol_tEditFieldLabel.Visible = 'off';
            app.d0EditField.Visible = 'off';
            app.d0EditFieldLabel.Visible = 'off';
            app.MEditField.Visible = "on";
            app.MEditFieldLabel.Visible = "on";
            app.stepEditField.Visible = "off";
            app.stepEditFieldLabel.Visible = "off";
        end

        function configuration_for_curve(app)
            app.Tol_NEditField.Visible = 'on';
            app.Tol_NEditFieldLabel.Visible = 'on';
            app.Tol_tEditField.Visible = 'on';
            app.Tol_tEditFieldLabel.Visible = 'on';
            app.d0EditField.Visible = 'on';
            app.d0EditFieldLabel.Visible = 'on';
            app.MEditField.Visible = "off";
            app.MEditFieldLabel.Visible = "off";
            app.stepEditField.Visible = "on";
            app.stepEditFieldLabel.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            mydlg = waitbar(0,"Starting parpool please wait");
            p = gcp('nocreate');
            addpath("progress_bar\");
            addpath("grid");
            addpath("both");
            addpath("curveTracing\");
            addpath("perf_test\");
            addpath("matrix_generation\");
            if isempty(p)
                parpool("Processes");
            end
            try 
                close(mydlg);
            catch   
            end
            % Afficher l'interface utilisateur de l'application
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %si la matrice est vide ou que la matrice n'est pas une matrice
            %load by l'utilisateur, la matrice est généré en utilisant la
            %fonction stoqué dans la variable generate matrix. 
            delete(app.roi);
            cla(app.UIAxes);

            app.perf_computed = false;
            app.selectareaButton.Visible = "off";
            app.StartButton.Visible = 'off';
            app.performancetestButton.Visible = "off";
            if app.matriceListBox.Value ~= "load" || isempty(app.A)
                app.A = app.generate_matrix(app.N);
            end
            %lorsque nous aurons fait le second algo, gridPseud... sera
            %aussi stoqué dans une variable et choisi en fonction du bouton
            %sélectionné.
            disableDefaultInteractivity(app.UIAxes);
            
            %Grid algo case
            switch app.id
                case 1 
                    [X,Y,sigmin] = gridPseudospectrum_par(app.A,app.epsilon,app.thread, app.MEditField.Value);  
                    contourf(app.UIAxes,X, Y, log10(sigmin),log10([app.epsilon app.epsilon]));
                    app.selectareaButton.Visible = "on";
                    app.DisplayButtonGroup.Visible = "off";
            %Comapre case
                case 2
                    disp("compare");
                    [app.h, app.sct] = app.algo(app.A,app.epsilon,app.MEditField.Value, app.d0,app.tol_Newton,app.tol_turn,app.thread,app.steps,app.UIAxes);
                    app.DisplayButtonGroup.Visible = "on";
            %Predict and correctcase
                otherwise
                    disp("curve");
                    [eigen_values, points] = app.algo(app.A,app.epsilon, app.d0,app.tol_Newton, app.tol_turn,app.thread,app.steps);
                    display_curveTracing(eigen_values,points,app.UIAxes);
                    app.DisplayButtonGroup.Visible = "off";
            end
            app.StartButton.Visible = 'on';
            app.performancetestButton.Visible = "on";
        end

        % Value changed function: matriceListBox
        function matriceListBoxValueChanged(app, event)
            value = app.matriceListBox.Value;
            %La matrice est choisie par l'utilisateur. Lorsque celle-ci
            %est changé, il est fournit la fonction de génération de la
            %matrice dans la variable generate matrix.
            switch value
                case "toeplitx"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix =  @(n) toeplitz_matrix(n);
                case "chebspec"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix = @(n) chebspec(n);
                case "random"
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
                    app.generate_matrix = @(n) random(n);
                case "load" 
                    if app.stress_test == false
                        app.NEditField.Visible = 'off';
                        app.NEditFieldLabel.Visible = 'off';
                        app.LoadButton.Visible = 'on';
                    end
                otherwise
                    app.generate_matrix =  @(n) toeplitz_matrix(n);
                    app.NEditField.Visible = 'on';
                    app.NEditFieldLabel.Visible = 'on';
                    app.LoadButton.Visible = 'off';
            end
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
           app.N = app.NEditField.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile( ...
                                             {'*.mat;',  'All Files (*.*)'}, 'Pick a File to Import');
            disp(filename);
            disp(pathname);
            disp( all(pathname ~= 0));
            %vérifie qu'il y a bien un fichier fourni par l'utilisateur.
            if all(filename ~=0) && all(pathname ~= 0)
                full_filename = fullfile(pathname, filename);
                app.A = importdata(full_filename);
            end
        end

        % Button pushed function: selectareaButton
        function selectareaButtonPushed(app, event)
            %changer M  xx
            app.roi = drawrectangle(app.UIAxes);
            addlistener(app.roi,'MovingROI',@app.allevents);
            addlistener(app.roi,'DeletingROI',@app.deleteRoi);
            app.configuration_for_select_area()
            % donne xmin, ymin, distance par rapport à xmin, distance par
            % rapport à ymin
            position = app.roi.Position;
            disp(position(2));
            app.xminEditField.Value = position(1);
            app.yminEditField.Value = position(2);
            app.xmaxEditField.Value = position(1) + position(3);
            app.ymaxEditField.Value = position(2) + position(4);
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            value = app.EpsilonEditField.Value;
            
            %sépart les valeurs et enlève les virgules 
            app.epsilon = strsplit(value,',');
            %les convertis en double
            app.epsilon = str2double(app.epsilon);
            if size(app.epsilon) == [1 1]
                app.epsilon(2) = app.epsilon(1);
            end
            %disp(app.epsilon);
            %s = handles.edit1.String; 
           
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.quitting_for_select_area();
            delete(app.roi);
            app.stress_test = false;
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            delete(app.roi);
            app.quitting_for_select_area();
            app.selectareaButton.Visible = "off";
            app.RecalculateButton.Enable = 'off';
            app.RecalculateButton.Visible = 'off';
            %lorsque nous aurons fait le second algo, gridPseud... sera
            %aussi stoqué dans une variable et choisi en fonction du bouton
            %sélectionné.
            [X,Y,sigmin] = gridPseudospectrum_par_r(app.A,app.threadsSpinner.Value, app.xminEditField.Value, app.xmaxEditField.Value, app.yminEditField.Value, app.ymaxEditField.Value, app.MEditField.Value);   
            contourf(app.UIAxes,X, Y, log10(sigmin), log10([app.epsilon app.epsilon]));
            colorbar(app.UIAxes,'eastoutside','FontName', "\epsilon");
            app.selectareaButton.Visible = "on";
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: performancetestButton
        function performancetestButtonPushed(app, event)
            app.configuration_for_perf_test();
            app.stress_test = true;
        end

        % Value changed function: threadsSpinner
        function threadsSpinnerValueChanged(app, event)
            app.thread = app.threadsSpinner.Value;
            
            if app.thread > app.max_tread
                app.threadsSpinner.Value = app.max_tread;
                app.thread = app.max_tread;
            end
        end

        % Value changed function: StartmeasurementsButton
        function StartmeasurementsButtonValueChanged(app, event)
            delete(app.roi);
            cla(app.UIAxes);
            app.Switch.Enable = "off";
            app.DisplayButtonGroup.Visible = "off";
            switch app.id
                %grid
                case 1 
                    [app.time_mesurment_curve,app.size_matrix,app.threads] = stress_test_grid(app.from_size,app.to_size,app.step,app.from_thread,app.to_thread,app.step_thread,app.generate_matrix,app.epsilon,app.MEditField.Value);

                % curve
                otherwise
                    [app.time_mesurment_curve,app.size_matrix,app.threads] = stress_test_curve(app.from_size,app.to_size,app.step,app.from_thread,app.to_thread,app.step_thread,app.generate_matrix,app.epsilon,app.tol_Newton,app.tol_turn,app.steps);
            end
            if app.switch_S_E == "Efficiency"
                Efficienty(app.time_mesurment_curve,app.size_matrix,app.threads,app.UIAxes);
            else
                SpeedUp(app.time_mesurment_curve,app.size_matrix,app.threads,app.UIAxes);
            end
            app.perf_computed = true;

            app.Switch.Enable = "on";
        end

        % Value changed function: tothreadSpinner
        function tothreadSpinnerValueChanged(app, event)
            app.to_thread = app.tothreadSpinner.Value;
            if app.to_thread <= app.from_thread
                app.to_thread = app.from_thread + 1;
                app.tothreadSpinner.Value = app.from_thread + 1;
            end
            if app.to_thread > app.max_tread
                app.tothreadSpinner.Value = app.max_tread;
                app.to_thread = app.max_tread;
            end
        end

        % Value changed function: fromthreadSpinner
        function fromthreadSpinnerValueChanged(app, event)
            app.from_thread = app.fromthreadSpinner.Value;
            if app.to_thread <= app.from_thread
                app.from_thread = app.to_thread - 1;
                app.fromthreadSpinner.Value = app.to_thread - 1;
            end
        end

        % Value changing function: tothreadSpinner
        function tothreadSpinnerValueChanging(app, event)
            app.to_thread = app.tothreadSpinner.Value;
            if app.to_thread <= app.from_thread
                app.to_thread = app.from_thread + 1;
                app.tothreadSpinner.Value = app.from_thread + 1;
            end
            if app.to_thread > app.max_tread
                app.tothreadSpinner.Value = app.max_tread;
                app.to_thread = app.max_tread;
            end
            
        end

        % Value changing function: fromthreadSpinner
        function fromthreadSpinnerValueChanging(app, event)
            app.from_thread = app.fromthreadSpinner.Value;
            if app.to_thread <= app.from_thread
                app.from_thread = app.to_thread - 1;
                app.fromthreadSpinner.Value = app.to_thread - 1;
            end
        end

        % Value changed function: step_threadSpinner
        function step_threadSpinnerValueChanged(app, event)
            value = app.step_threadSpinner.Value;
            if value <= app.to_thread - app.from_thread
                app.step_thread = value;
            else
                app.step_threadSpinner.Value = app.step_thread;
            end
        end

        % Value changed function: tosizeSpinner
        function tosizeSpinnerValueChanged(app, event)
            app.to_size = app.tosizeSpinner.Value;
            if app.to_size <= app.from_size
                app.to_size = app.from_size + 1;
                app.tosizeSpinner.Value = app.from_size + 1;
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Grid"
                    disp("Grid");
                    app.h.Visible = 'on';
                    
                    for i=1:length(app.sct)
                        app.sct{i}.Visible="off";
                    end
                case "Curve Tracing"
                    disp("curve");
                    app.h.Visible = 'off';
                    for i=1:length(app.sct)
                        app.sct{i}.Visible="on";
                    end
                otherwise
                    disp("both");
                    app.h.Visible = 'on';
                    for i=1:length(app.sct)
                        app.sct{i}.Visible='on';
                    end
                    
            end
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmButtonGroup.SelectedObject;
            switch selectedButton.Text 
                case "Grid"
                    app.algo = app.grid;
                    app.id = 1;
                    app.configuration_for_grid();
                    %Change the info display
                case "Compare"
                    app.algo = app.both;
                    app.id=2;
                    app.configuration_for_both_algo();
                otherwise
                    app.algo = app.curve_tracing;
                    app.id=3;
                    app.configuration_for_curve();
            end

        end

        % Value changed function: Tol_NEditField
        function Tol_NEditFieldValueChanged(app, event)
            value = app.Tol_NEditField.Value;
            if value < app.epsilon 
                app.tol_Newton = value;
            else 
                app.Tol_NEditField.Value = app.tol_Newton;
            end
        end

        % Value changed function: Tol_tEditField
        function Tol_tEditFieldValueChanged(app, event)
            value = app.Tol_tEditField.Value;
            if value < app.epsilon 
                app.tol_turn = value;
            else 
                app.Tol_tEditField.Value = app.tol_turn;
            end
        end

        % Value changed function: stepEditField
        function stepEditFieldValueChanged(app, event)
            value = app.stepEditField.Value;
            if value<= app.tol_turn 
                app.stepEditField.Value = app.steps;
            else 
                app.steps = value;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.switch_S_E = app.Switch.Value;
            if app.perf_computed 
                delete(app.roi);
                cla(app.UIAxes);
                if app.switch_S_E == "Efficiency"
                    Efficienty(app.time_mesurment_curve,app.size_matrix,app.threads,app.UIAxes);
                else
                    SpeedUp(app.time_mesurment_curve,app.size_matrix,app.threads,app.UIAxes);
                end
            end
        end

        % Value changed function: fromsizeSpinner_2
        function fromsizeSpinner_2ValueChanged(app, event)
            app.from_size = app.step_threadSpinner.Value;
            if app.to_size <= app.from_size
                app.from_size = app.to_size - 1;
                app.step_threadSpinner.Value = app.to_size - 1;
            end
            
        end

        % Value changed function: step_sizeSpinner
        function step_sizeSpinnerValueChanged(app, event)
            value = app.step_sizeSpinner.Value;
            if value <= app.to_size - app.from_size
                app.step = value;
            else
                app.step_sizeSpinner.Value = app.step;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Log10 of the smallest singular value of (\lambdaI - A)')
            xlabel(app.UIAxes, 'Real part (\lambda)')
            ylabel(app.UIAxes, 'Imaginary part (\lambda)')
            zlabel(app.UIAxes, 'Z')
            app.threadsSpinnerLabel = uilabel(app.UIFigure);
            app.threadsSpinnerLabel.Text = 'threads';
            app.threadsSpinner = uispinner(app.UIFigure);
            app.threadsSpinner.Limits = [1 Inf];
            app.threadsSpinner.ValueChangedFcn = createCallbackFcn(app, @threadsSpinnerValueChanged, true);
            app.threadsSpinner.Value = 1;
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.GridButton_2 = uiradiobutton(app.AlgorithmButtonGroup);
            app.GridButton_2.Text = 'Grid';
            app.GridButton_2.Value = true;
            app.CurveTracingButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.CurveTracingButton.Text = 'Curve Tracing';
            app.CompareButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.CompareButton.Text = 'Compare';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.matriceListBoxLabel = uilabel(app.UIFigure);
            app.matriceListBoxLabel.Text = 'matrice';
            app.matriceListBox = uilistbox(app.UIFigure);
            app.matriceListBox.Items = {'toeplitz', 'chebspec', 'random', 'load'};
            app.matriceListBox.ValueChangedFcn = createCallbackFcn(app, @matriceListBoxValueChanged, true);
            app.matriceListBox.Value = 'toeplitz';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 50;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Visible = 'off';
            app.LoadButton.Text = 'Load';
            app.performancetestButton = uibutton(app.UIFigure, 'push');
            app.performancetestButton.ButtonPushedFcn = createCallbackFcn(app, @performancetestButtonPushed, true);
            app.performancetestButton.Text = 'performance test ';
            app.selectareaButton = uibutton(app.UIFigure, 'push');
            app.selectareaButton.ButtonPushedFcn = createCallbackFcn(app, @selectareaButtonPushed, true);
            app.selectareaButton.Visible = 'off';
            app.selectareaButton.Text = 'select area';
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Enable = 'off';
            app.xminEditFieldLabel.Visible = 'off';
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Editable = 'off';
            app.xminEditField.Enable = 'off';
            app.xminEditField.Visible = 'off';
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Enable = 'off';
            app.xmaxEditFieldLabel.Visible = 'off';
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Editable = 'off';
            app.xmaxEditField.Enable = 'off';
            app.xmaxEditField.Visible = 'off';
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Enable = 'off';
            app.yminEditFieldLabel.Visible = 'off';
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Editable = 'off';
            app.yminEditField.Enable = 'off';
            app.yminEditField.Visible = 'off';
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Enable = 'off';
            app.ymaxEditFieldLabel.Visible = 'off';
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Editable = 'off';
            app.ymaxEditField.Enable = 'off';
            app.ymaxEditField.Visible = 'off';
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'text');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Value = '0.1';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel';
            app.RecalculateButton = uibutton(app.UIFigure, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Enable = 'off';
            app.RecalculateButton.Visible = 'off';
            app.RecalculateButton.Text = 'Recalculate';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.Limits = [1 Inf];
            app.MEditField.RoundFractionalValues = 'on';
            app.MEditField.Value = 20;
            app.StartmeasurementsButton = uibutton(app.UIFigure, 'state');
            app.StartmeasurementsButton.ValueChangedFcn = createCallbackFcn(app, @StartmeasurementsButtonValueChanged, true);
            app.StartmeasurementsButton.Enable = 'off';
            app.StartmeasurementsButton.Visible = 'off';
            app.StartmeasurementsButton.Text = 'Start measurements';
            app.fromthreadSpinnerLabel = uilabel(app.UIFigure);
            app.fromthreadSpinnerLabel.Enable = 'off';
            app.fromthreadSpinnerLabel.Visible = 'off';
            app.fromthreadSpinnerLabel.Text = 'from thread';
            app.fromthreadSpinner = uispinner(app.UIFigure);
            app.fromthreadSpinner.ValueChangingFcn = createCallbackFcn(app, @fromthreadSpinnerValueChanging, true);
            app.fromthreadSpinner.Limits = [1 Inf];
            app.fromthreadSpinner.ValueChangedFcn = createCallbackFcn(app, @fromthreadSpinnerValueChanged, true);
            app.fromthreadSpinner.Enable = 'off';
            app.fromthreadSpinner.Visible = 'off';
            app.fromthreadSpinner.Value = 1;
            app.tothreadSpinnerLabel = uilabel(app.UIFigure);
            app.tothreadSpinnerLabel.Enable = 'off';
            app.tothreadSpinnerLabel.Visible = 'off';
            app.tothreadSpinnerLabel.Text = 'to thread';
            app.tothreadSpinner = uispinner(app.UIFigure);
            app.tothreadSpinner.ValueChangingFcn = createCallbackFcn(app, @tothreadSpinnerValueChanging, true);
            app.tothreadSpinner.Limits = [2 Inf];
            app.tothreadSpinner.ValueChangedFcn = createCallbackFcn(app, @tothreadSpinnerValueChanged, true);
            app.tothreadSpinner.Enable = 'off';
            app.tothreadSpinner.Visible = 'off';
            app.tothreadSpinner.Value = 2;
            app.step_threadSpinnerLabel = uilabel(app.UIFigure);
            app.step_threadSpinnerLabel.Visible = 'off';
            app.step_threadSpinnerLabel.Text = 'step_thread';
            app.step_threadSpinner = uispinner(app.UIFigure);
            app.step_threadSpinner.Limits = [1 Inf];
            app.step_threadSpinner.ValueChangedFcn = createCallbackFcn(app, @step_threadSpinnerValueChanged, true);
            app.step_threadSpinner.Visible = 'off';
            app.step_threadSpinner.Value = 1;
            app.tosizeSpinnerLabel = uilabel(app.UIFigure);
            app.tosizeSpinnerLabel.Visible = 'off';
            app.tosizeSpinnerLabel.Text = 'to size';
            app.tosizeSpinner = uispinner(app.UIFigure);
            app.tosizeSpinner.Limits = [2 Inf];
            app.tosizeSpinner.ValueChangedFcn = createCallbackFcn(app, @tosizeSpinnerValueChanged, true);
            app.tosizeSpinner.Visible = 'off';
            app.tosizeSpinner.Value = 20;
            app.DisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.DisplayButtonGroup.Visible = 'off';
            app.BothButton = uiradiobutton(app.DisplayButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.GridButton = uiradiobutton(app.DisplayButtonGroup);
            app.GridButton.Text = 'Grid';
            app.CurveTracingButton_2 = uiradiobutton(app.DisplayButtonGroup);
            app.CurveTracingButton_2.Text = 'Curve Tracing';
            app.d0EditFieldLabel = uilabel(app.UIFigure);
            app.d0EditFieldLabel.Visible = 'off';
            app.d0EditFieldLabel.Text = 'd0';
            app.d0EditField = uieditfield(app.UIFigure, 'text');
            app.d0EditField.Visible = 'off';
            app.d0EditField.Value = '1i';
            app.Tol_NEditFieldLabel = uilabel(app.UIFigure);
            app.Tol_NEditFieldLabel.Visible = 'off';
            app.Tol_NEditFieldLabel.Text = 'Tol_N';
            app.Tol_NEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tol_NEditField.Limits = [0 Inf];
            app.Tol_NEditField.ValueChangedFcn = createCallbackFcn(app, @Tol_NEditFieldValueChanged, true);
            app.Tol_NEditField.Visible = 'off';
            app.Tol_NEditField.Value = 0.01;
            app.Tol_tEditFieldLabel = uilabel(app.UIFigure);
            app.Tol_tEditFieldLabel.Visible = 'off';
            app.Tol_tEditFieldLabel.Text = 'Tol_t';
            app.Tol_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tol_tEditField.Limits = [0 Inf];
            app.Tol_tEditField.ValueChangedFcn = createCallbackFcn(app, @Tol_tEditFieldValueChanged, true);
            app.Tol_tEditField.Visible = 'off';
            app.Tol_tEditField.Value = 0.01;
            app.stepEditFieldLabel = uilabel(app.UIFigure);
            app.stepEditFieldLabel.Visible = 'off';
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.UIFigure, 'text');
            app.stepEditField.ValueChangedFcn = createCallbackFcn(app, @stepEditFieldValueChanged, true);
            app.stepEditField.Visible = 'off';
            app.stepEditField.Value = '0.1';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Efficiency', 'Speedup'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Efficiency';
            app.fromsizeSpinner_2Label = uilabel(app.UIFigure);
            app.fromsizeSpinner_2Label.Visible = 'off';
            app.fromsizeSpinner_2Label.Text = 'from size';
            app.fromsizeSpinner_2 = uispinner(app.UIFigure);
            app.fromsizeSpinner_2.Limits = [1 Inf];
            app.fromsizeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @fromsizeSpinner_2ValueChanged, true);
            app.fromsizeSpinner_2.Visible = 'off';
            app.fromsizeSpinner_2.Value = 10;
            app.step_sizeSpinnerLabel = uilabel(app.UIFigure);
            app.step_sizeSpinnerLabel.Visible = 'off';
            app.step_sizeSpinnerLabel.Text = 'step_size';
            app.step_sizeSpinner = uispinner(app.UIFigure);
            app.step_sizeSpinner.Limits = [1 Inf];
            app.step_sizeSpinner.ValueChangedFcn = createCallbackFcn(app, @step_sizeSpinnerValueChanged, true);
            app.step_sizeSpinner.Visible = 'off';
            app.step_sizeSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end